classdef mslurmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mSlurmAppUIFigure         matlab.ui.Figure
        FileMenu                  matlab.ui.container.Menu
        OutputLogMenu             matlab.ui.container.Menu
        OutputLogForceRemoteMenu  matlab.ui.container.Menu
        BashMenu                  matlab.ui.container.Menu
        RetryMenu                 matlab.ui.container.Menu
        CancelMenu                matlab.ui.container.Menu
        TechincalInfoMenu         matlab.ui.container.Menu
        GetfevalresultMenu        matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        JobsTab                   matlab.ui.container.Tab
        LogPanel                  matlab.ui.container.Panel
        StatusHTML                matlab.ui.control.HTML
        JobsTable                 matlab.ui.control.Table
        SelectJobsPanel           matlab.ui.container.Panel
        ToTimeSlider              matlab.ui.control.Slider
        TimeSliderLabel_2         matlab.ui.control.Label
        FromTimeSlider            matlab.ui.control.Slider
        TimeSliderLabel           matlab.ui.control.Label
        FairShareEditField        matlab.ui.control.NumericEditField
        FairShareEditFieldLabel   matlab.ui.control.Label
        RefreshButton             matlab.ui.control.Button
        UserEditField             matlab.ui.control.EditField
        UserEditFieldLabel        matlab.ui.control.Label
        ToDatePicker              matlab.ui.control.DatePicker
        ToDatePickerLabel         matlab.ui.control.Label
        FromDatePicker            matlab.ui.control.DatePicker
        FromDatePickerLabel       matlab.ui.control.Label
        GroupsTable               matlab.ui.control.Table
        MapTab                    matlab.ui.container.Tab
        MapRefreshButton          matlab.ui.control.Button
        MapTable                  matlab.ui.control.Table
        MapGroupTable             matlab.ui.control.Table
        DetailTab                 matlab.ui.container.Tab
        jobDetailsTable           matlab.ui.control.Table
    end

    
    properties (Access = private)
        hSlurm % A handle to the mslurm object, which connects to the HPC cluster.
        allJobs;
        
        groupsSelection=table('Size',[0 2],'VariableTypes',{'string','string'},'VariableNames',{'Group','State'});
        jobsSelection; % Description
        
        allMaps;
        mapGroupSelection = table('Size',[0 2],'VariableTypes',{'string','string'},'VariableNames',{'USER','STATE'});
    end
    
    methods (Access = private)
        
        function refresh(app)
            app.workingPointer;
            
            %% Collect from SLURM and update display.
            % This will update .jobs in the hSlurm
            from= app.FromDatePicker.Value + hours(app.FromTimeSlider.Value);
            to = app.ToDatePicker.Value + hours(app.ToTimeSlider.Value);
            app.hSlurm.sacct('format','jobId,State,ExitCode,jobName,Comment,submit,elapsed,NodeList,end','user',app.UserEditField.Value,'starttime',from,'endtime',to); % Update from Slrum
            if app.hSlurm.isConnected
                app.mSlurmAppUIFigure.Name= "Slurm App: Connected to " +  app.hSlurm.host +  " as " +  app.hSlurm.user;
            else
                app.mSlurmAppUIFigure.Name = "Slurm App: Not Connected";
            end
            jTable = jobsTable(app.hSlurm); % Get the .jobs as a table
            
            if isempty(jTable)
                % No jobs. Alert
                app.status('No jobs found for the current selection');
                app.allJobs = table;
                app.GroupsTable.Data = table;
                app.JobsTable.Data = table;
                app.GroupsTable.Enable = "off";
                app.JobsTable.Enable = "off";
            else
                
                jTable = removevars(jTable,{'JobName','Comment'});
                jTable = movevars(jTable,'End','After','Submit');
                gTable =  groupcounts(jTable,{'Group','State'});
                gTable= renamevars(gTable,'GroupCount','Count');
                
                
                app.allJobs = jTable;
                app.JobsTable.ColumnName = jTable.Properties.VariableNames;
                
                app.GroupsTable.Data =gTable;
                % See if we can reselect what was selected before
                app.groupsSelection = intersect(app.groupsSelection,gTable(:,{'Group','State'}));
                addStyle(app.GroupsTable,uistyle("HorizontalAlignment","left"),'column',3);
                updateJobsTable(app);
                
                app.FairShareEditField.Value = app.hSlurm.fairshare(app.UserEditField.Value);
                app.GroupsTable.Enable = "on";
                app.JobsTable.Enable = "on";
            end
            app.status("SLURM Job Info Refreshed.")
            app.donePointer;
            
        end
        
        function updateJobsTable(app)
            if isempty(app.groupsSelection)
                app.JobsTable.Data = app.allJobs;
            else
                stay =ismember(app.allJobs(:,{'Group','State'}),app.groupsSelection);
                app.JobsTable.Data = app.allJobs(stay,:);
            end
            
        end
        
        function updateMapTable(app)
            if isempty(app.mapGroupSelection)
                app.MapTable.Data = app.allMaps;
            else
                stay =ismember(app.allMaps(:,{'USER','STATE'}),app.mapGroupSelection);
                app.MapTable.Data = app.allMaps(stay,:);
            end
            
        end
        
        
        function status(app,msg,varargin)
            % Append to HTML
            if ischar(msg)
                newMsg  =sprintf(msg,varargin{:});
            else % Cell
                newMsg = sprintf('%s<BR>',msg{:});
            end
            app.StatusHTML.HTMLSource = cat(2, sprintf('<p style="font-size:11" ><b>%s</b>: %s</p><HR/>',char(datetime("now",'Format','HH:mm:SS')),newMsg),app.StatusHTML.HTMLSource);
            
        end
        
        function workingPointer(app)
            app.mSlurmAppUIFigure.Pointer= 'watch';
            drawnow;
        end
        function donePointer(app)
            app.mSlurmAppUIFigure.Pointer= 'arrow';
            drawnow;
        end
        
        function retrieveFile(app,event,type)
            app.workingPointer;
            if ~isempty(app.jobsSelection)
                forceRemote = contains(event.Source.Text,'Force Remote');
                [localName,msg] = app.hSlurm.getFile(app.JobsTable.Data{app.jobsSelection,'JobID'},'type',type,'forceRemote',forceRemote);
                if isempty(msg)
                    if iscell(localName)
                        edit(localName{:})
                    else
                        edit(localName);
                    end
                else
                    app.status(msg)
                end
            end
            app.donePointer;
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hSlurm)
            if nargin<2 
                hSlurm =mslurm; % Create a default mslurm object, based on saved preferences.
                connect(hSlurm); % Try to connect
                if ~hSlurm.isConnected
                    hSlurm
                    error('Could not connect to SLURM.')
                end
            end
            if ~isa(hSlurm,'mslurm')
                error('The Slurm App requires a mslurm object to run. Please create one first with mslurm(), or install a default with mslurm.install')
            end
            %% Install the icon post R2020b
            if ~verLessThan('matlab','9.9')
                app.mSlurmAppUIFigure.Icon = fullfile(fileparts(mfilename('fullpath')),'icon.png');
            end
            
            %% Set initial values
            app.FromDatePicker.Value = datetime('today');
            app.ToDatePicker.Value= datetime('today');
            app.UserEditField.Value = hSlurm.user;
            app.hSlurm = hSlurm;
            app.status(app.mSlurmAppUIFigure.Name);
            app.refresh
            
        end

        % Cell selection callback: GroupsTable
        function GroupsTableCellSelection(app, event)
            ix = event.Indices;
            app.groupsSelection = app.GroupsTable.Data(ix(:,1),{'Group','State'});
            updateJobsTable(app)
        end

        % Menu selected function: OutputLogForceRemoteMenu, OutputLogMenu
        function OutputLogMenuSelected(app, event)
            retrieveFile(app,event,'out');
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            refresh(app)
        end

        % Cell selection callback: JobsTable
        function JobsTableCellSelection(app, event)
            ix = event.Indices;
            app.jobsSelection = ix(:,1);
            st = uistyle("BackgroundColor",'g');
            removeStyle(app.JobsTable);
            addStyle(app.JobsTable,st,'row',ix(:,1));
            
        end

        % Menu selected function: BashMenu
        function BashMenuSelected(app, event)
            retrieveFile(app,event,'sh');
        end

        % Button pushed function: MapRefreshButton
        function MapRefreshButtonPushed(app, event)
            app.workingPointer
            [allMapInfo ,msg] = smap(app.hSlurm);
            if isempty(allMapInfo)
                msg = 'smap did not return Map information. Probably smap is not installed on the cluster?';
                app.MapGroupTable.ColumnName = msg;                
            else
            isRunning = ~ismember(allMapInfo{:,'NODELIST'},'waiting...');
            state = cell(height(allMapInfo),1);
            [state{isRunning}] = deal('running...');
            [state{~isRunning}] = deal('waiting...');
            allMapInfo = addvars(allMapInfo,state,'NewVariableNames','STATE');
            app.allMaps = allMapInfo;
            app.MapTable.ColumnName = app.allMaps.Properties.VariableNames;
            groupT = groupcounts(app.allMaps,{'USER','STATE'});
            app.MapGroupTable.Data = groupT;
            app.MapGroupTable.ColumnName = {'User','State','#'};
            addStyle(app.MapGroupTable,uistyle("HorizontalAlignment","left"),'column',3);
            
            % See if we can reselect what was selected before
            app.mapGroupSelection = intersect(app.mapGroupSelection,groupT(:,{'USER','STATE'}));
            updateMapTable(app);
            
            app.MapGroupTable.Enable = "on";
            app.MapTable.Enable = "on";
            end
            status(app,msg);
            app.donePointer
        end

        % Cell selection callback: MapGroupTable
        function MapGroupTableCellSelection(app, event)
            ix = event.Indices;
            app.mapGroupSelection = app.MapGroupTable.Data(ix(:,1),{'USER','STATE'});
            updateMapTable(app)
        end

        % Menu selected function: RetryMenu
        function RetryMenuSelected(app, event)
            if isempty(app.jobsSelection)
                app.status('No jobs selected. Nothing to retry');
            else
                app.status('Retrying %d jobs ',numel(app.jobsSelection));
                app.workingPointer;
                app.hSlurm.retry('jobId',app.JobsTable.Data{app.jobsSelection,'JobID'})
                app.donePointer;
            end
        end

        % Menu selected function: CancelMenu
        function CancelMenuSelected(app, event)
            if isempty(app.jobsSelection)
                app.status('No jobs selected. Nothing to cancel');
            else
                app.status('Canceling %d jobs ',numel(app.jobsSelection));
                app.workingPointer;
                app.hSlurm.cancel('jobId',app.JobsTable.Data{app.jobsSelection,'JobID'})
                app.refresh;
                app.donePointer;
            end
        end

        % Menu selected function: TechincalInfoMenu
        function TechincalInfoMenuSelected(app, event)
            frmt = 'AveCPU,AvePages,AveRSS,AveVMSize,cputime,maxRSS,maxVMSize,ReqMem,NodeList,State';
            %jobId = strsplit(jobId,'_');% In case this is an arry job
            if isempty(app.jobsSelection)
                app.status('No jobs selected. Nothing to show');
            else
                app.workingPointer;
                slurmJobDetails = app.hSlurm.sacct('jobId',app.JobsTable.Data{app.jobsSelection,'JobID'},'format',frmt,'removeSteps',false);
                T = struct2table(slurmJobDetails,'AsArray',true);
                T =movevars(T,'JobID','Before',1);
                app.jobDetailsTable.Data = T;
                app.jobDetailsTable.ColumnName  = T.Properties.VariableNames;
                app.donePointer;
                app.TabGroup.SelectedTab = app.DetailTab;
                app.jobDetailsTable.Enable = "on";
            end
            
        end

        % Menu selected function: GetfevalresultMenu
        function GetfevalresultMenuSelected(app, event)
            if isempty(app.jobsSelection)
                app.status('No jobs selected. Nothing to show');
            else
                jobId = app.JobsTable.Data{app.jobsSelection,'JobID'};
                tag =app.hSlurm.jobs(ismember({app.hSlurm.jobs.JobID},jobId)).JobName;
                data = app.hSlurm.retrieve(tag);
                assignin('base','data',data)
                app.status('%d results of the job have been assigned to ''data'' in the base worksapce',numel(data));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mSlurmAppUIFigure = uifigure('Visible', 'off');
            app.mSlurmAppUIFigure.Name = 'mSlurm App';
            app.FileMenu = uimenu(app.mSlurmAppUIFigure);
            app.FileMenu.Text = 'File';
            app.OutputLogMenu = uimenu(app.FileMenu);
            app.OutputLogMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputLogMenuSelected, true);
            app.OutputLogMenu.Tooltip = {'Retrieve the command line output log file.'};
            app.OutputLogMenu.Accelerator = 'o';
            app.OutputLogMenu.Text = 'Output Log';
            app.OutputLogForceRemoteMenu = uimenu(app.FileMenu);
            app.OutputLogForceRemoteMenu.MenuSelectedFcn = createCallbackFcn(app, @OutputLogMenuSelected, true);
            app.OutputLogForceRemoteMenu.Tooltip = {'Retrieve the command line output log file from the SLURM cluster (even if it already exists locally)'};
            app.OutputLogForceRemoteMenu.Accelerator = 'P';
            app.OutputLogForceRemoteMenu.Text = 'Output Log (Force Remote)';
            app.BashMenu = uimenu(app.FileMenu);
            app.BashMenu.MenuSelectedFcn = createCallbackFcn(app, @BashMenuSelected, true);
            app.BashMenu.Tooltip = {'Retrieve the bash shell script used to start this job.'};
            app.BashMenu.Accelerator = 's';
            app.BashMenu.Text = 'Bash Script';
            app.RetryMenu = uimenu(app.FileMenu);
            app.RetryMenu.MenuSelectedFcn = createCallbackFcn(app, @RetryMenuSelected, true);
            app.RetryMenu.Accelerator = 'r';
            app.RetryMenu.Text = 'Retry';
            app.CancelMenu = uimenu(app.FileMenu);
            app.CancelMenu.MenuSelectedFcn = createCallbackFcn(app, @CancelMenuSelected, true);
            app.CancelMenu.Accelerator = 'c';
            app.CancelMenu.Text = 'Cancel';
            app.TechincalInfoMenu = uimenu(app.FileMenu);
            app.TechincalInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @TechincalInfoMenuSelected, true);
            app.TechincalInfoMenu.Tooltip = {'Show techincal info about this job.'};
            app.TechincalInfoMenu.Accelerator = 't';
            app.TechincalInfoMenu.Text = 'Techincal Info';
            app.GetfevalresultMenu = uimenu(app.FileMenu);
            app.GetfevalresultMenu.MenuSelectedFcn = createCallbackFcn(app, @GetfevalresultMenuSelected, true);
            app.GetfevalresultMenu.Accelerator = 'g';
            app.GetfevalresultMenu.Text = 'Get feval result';
            app.TabGroup = uitabgroup(app.mSlurmAppUIFigure);
            app.JobsTab = uitab(app.TabGroup);
            app.JobsTab.Title = 'Jobs';
            app.GroupsTable = uitable(app.JobsTab);
            app.GroupsTable.ColumnName = {'Group'; 'State'; '#'};
            app.GroupsTable.RowName = {};
            app.GroupsTable.ColumnSortable = true;
            app.GroupsTable.CellSelectionCallback = createCallbackFcn(app, @GroupsTableCellSelection, true);
            app.SelectJobsPanel = uipanel(app.JobsTab);
            app.SelectJobsPanel.Title = 'Select Jobs';
            app.FromDatePickerLabel = uilabel(app.SelectJobsPanel);
            app.FromDatePickerLabel.Text = 'From';
            app.FromDatePicker = uidatepicker(app.SelectJobsPanel);
            app.FromDatePicker.Tooltip = {'Show jobs that started on this date or later.'};
            app.ToDatePickerLabel = uilabel(app.SelectJobsPanel);
            app.ToDatePickerLabel.Text = 'To';
            app.ToDatePicker = uidatepicker(app.SelectJobsPanel);
            app.ToDatePicker.Tooltip = {'Show jobs that started until this date.'};
            app.UserEditFieldLabel = uilabel(app.SelectJobsPanel);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.SelectJobsPanel, 'text');
            app.RefreshButton = uibutton(app.SelectJobsPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Tooltip = {'Refresh information from the SLURM HPC Cluster.'};
            app.RefreshButton.Text = 'Refresh';
            app.FairShareEditFieldLabel = uilabel(app.SelectJobsPanel);
            app.FairShareEditFieldLabel.Text = 'Fair Share';
            app.FairShareEditField = uieditfield(app.SelectJobsPanel, 'numeric');
            app.FairShareEditField.Limits = [0 1];
            app.FairShareEditField.ValueDisplayFormat = '%.2f';
            app.FairShareEditField.Enable = 'off';
            app.FairShareEditField.Tooltip = {'The users FairShare score at the last refresh (1= good, 0=bad).'};
            app.TimeSliderLabel = uilabel(app.SelectJobsPanel);
            app.TimeSliderLabel.Text = 'Time';
            app.FromTimeSlider = uislider(app.SelectJobsPanel);
            app.FromTimeSlider.Limits = [0 24];
            app.TimeSliderLabel_2 = uilabel(app.SelectJobsPanel);
            app.TimeSliderLabel_2.Text = 'Time';
            app.ToTimeSlider = uislider(app.SelectJobsPanel);
            app.ToTimeSlider.Limits = [0 24];
            app.ToTimeSlider.Value = 24;
            app.JobsTable = uitable(app.JobsTab);
            app.JobsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.JobsTable.ColumnSortable = true;
            app.JobsTable.CellSelectionCallback = createCallbackFcn(app, @JobsTableCellSelection, true);
            app.LogPanel = uipanel(app.JobsTab);
            app.LogPanel.Title = 'Log';
            app.StatusHTML = uihtml(app.LogPanel);
            app.StatusHTML.Tooltip = {'Status messages will show here.'};
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.MapGroupTable = uitable(app.MapTab);
            app.MapGroupTable.ColumnName = {'Press refresh to retrieve a map of current cluster jobs'};
            app.MapGroupTable.RowName = {};
            app.MapGroupTable.ColumnSortable = true;
            app.MapGroupTable.CellSelectionCallback = createCallbackFcn(app, @MapGroupTableCellSelection, true);
            app.MapGroupTable.Enable = 'off';
            app.MapTable = uitable(app.MapTab);
            app.MapTable.ColumnName = {'User and node usage details will show here.'};
            app.MapTable.RowName = {};
            app.MapTable.ColumnSortable = true;
            app.MapTable.Enable = 'off';
            app.MapRefreshButton = uibutton(app.MapTab, 'push');
            app.MapRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @MapRefreshButtonPushed, true);
            app.MapRefreshButton.Tooltip = {'Refresh the Cluster Map'};
            app.MapRefreshButton.Text = 'Refresh';
            app.DetailTab = uitab(app.TabGroup);
            app.DetailTab.Title = 'Detail';
            app.jobDetailsTable = uitable(app.DetailTab);
            app.jobDetailsTable.ColumnName = {'Select job(s) in the Jobs tab then press Ctrl T to show job details here.'};
            app.jobDetailsTable.RowName = {};
            app.jobDetailsTable.ColumnSortable = true;
            app.jobDetailsTable.Tooltip = {''};
            app.jobDetailsTable.Enable = 'off';
            % Show the figure after all components are created
            app.mSlurmAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mslurmApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mSlurmAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mSlurmAppUIFigure)
        end
    end
end