classdef slurmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        JobGroupsListBoxLabel    matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        SelectJobsPanel          matlab.ui.container.Panel
        FromDatePickerLabel      matlab.ui.control.Label
        FromDatePicker           matlab.ui.control.DatePicker
        ToDatePickerLabel        matlab.ui.control.Label
        ToDatePicker             matlab.ui.control.DatePicker
        UserEditFieldLabel       matlab.ui.control.Label
        UserEditField            matlab.ui.control.EditField
        FairShareEditFieldLabel  matlab.ui.control.Label
        FairShareEditField       matlab.ui.control.NumericEditField
        JobsTable                matlab.ui.control.Table
        JobInfoText              matlab.ui.control.TextArea
        TotalJobsLabel           matlab.ui.control.Label
        GroupsTable              matlab.ui.control.Table
        HelpTab                  matlab.ui.container.Tab
        HTML                     matlab.ui.control.HTML
    end

    
    properties (Access = private)
        hSlurm % A handle to the slurm object, which connects to the HPC cluster.
        allJobs;
        
        groupsSelection=table('Size',[0 2],'VariableTypes',{'string','string'},'VariableNames',{'Group','State'});
    end
    
    methods (Access = private)
        
        function refresh(app)
            oldPointer = app.UIFigure.Pointer;
            app.UIFigure.Pointer= 'watch';
            
            %% Collect from SLURM and update display
            app.hSlurm.sacct('format','jobId,State,ExitCode,jobName,Comment,submit,elapsed,NodeList,end'); % Update from Slrum
            if app.hSlurm.isConnected
                app.UIFigure.Name= ['Slurm App: Connected to ' app.hSlurm.host ' as ' app.hSlurm.user];
            else
                app.UIFigure.Name = 'Slurm App: Not Connected';
            end
            app.TotalJobsLabel.Text = ['Total:' num2str(app.hSlurm.nrJobs) ' jobs'];
            
            
            jTable = jobsTable(app.hSlurm);
            jTable = removevars(jTable,{'JobName','Comment'});
            jTable = movevars(jTable,'End','After','Submit');
            gTable =  groupcounts(jTable,{'Group','State'});
            gTable= renamevars(gTable,'GroupCount','Count');
            
            
            app.allJobs = jTable;
            app.JobsTable.ColumnName = jTable.Properties.VariableNames;
            
            app.GroupsTable.Data =gTable;
            app.GroupsTable.ColumnName = gTable.Properties.VariableNames;
            % See if we can reselect what was selected before
            app.groupsSelection = intersect(app.groupsSelection,gTable(:,{'Group','State'}));
            updateJobsTable(app);
            
            app.FairShareEditField.Value = app.hSlurm.fairshare;
            app.UIFigure.Pointer= oldPointer;                
        end
        
        function updateJobsTable(app)            
            if isempty(app.groupsSelection)
                app.JobsTable.Data = app.allJobs;
            else
                stay =ismember(app.allJobs(:,{'Group','State'}),app.groupsSelection);                
                app.JobsTable.Data = app.allJobs(stay,:);
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hSlurm)
                if nargin<2 || ~isa(hSlurm,'slurm')
                    error('The Slurm App requires a slurm object to run. Please create one first with slurm().')
                end
                
                %% Set initial values
                app.FromDatePicker.Value = datetime('today');
                app.ToDatePicker.Value= datetime('today');
                app.UserEditField.Value = hSlurm.user;
                app.hSlurm = hSlurm;
                app.refresh
        end

        % Cell selection callback: GroupsTable
        function GroupsTableCellSelection(app, event)
                ix = event.Indices;
                app.groupsSelection = app.GroupsTable.Data(ix(:,1),{'Group','State'});
                updateJobsTable(app)
        end

        % Value changed function: UserEditField
        function UserEditFieldValueChanged(app, event)
            value = app.UserEditField.Value;
            app.hSlurm.user = value;
            refresh(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JobGroupsListBoxLabel = uilabel(app.UIFigure);
            app.JobGroupsListBoxLabel.Text = 'Job Groups';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.SelectJobsPanel = uipanel(app.Tab);
            app.SelectJobsPanel.Title = 'Select Jobs';
            app.FromDatePickerLabel = uilabel(app.SelectJobsPanel);
            app.FromDatePickerLabel.Text = 'From';
            app.FromDatePicker = uidatepicker(app.SelectJobsPanel);
            app.FromDatePicker.Tooltip = {'Show jobs that started on this date or later.'};
            app.ToDatePickerLabel = uilabel(app.SelectJobsPanel);
            app.ToDatePickerLabel.Text = 'To';
            app.ToDatePicker = uidatepicker(app.SelectJobsPanel);
            app.ToDatePicker.Tooltip = {'Show jobs that started until this date.'};
            app.UserEditFieldLabel = uilabel(app.SelectJobsPanel);
            app.UserEditFieldLabel.Text = 'User';
            app.UserEditField = uieditfield(app.SelectJobsPanel, 'text');
            app.UserEditField.ValueChangedFcn = createCallbackFcn(app, @UserEditFieldValueChanged, true);
            app.FairShareEditFieldLabel = uilabel(app.SelectJobsPanel);
            app.FairShareEditFieldLabel.Text = 'Fair Share';
            app.FairShareEditField = uieditfield(app.SelectJobsPanel, 'numeric');
            app.FairShareEditField.Limits = [0 1];
            app.FairShareEditField.ValueDisplayFormat = '%.3f';
            app.FairShareEditField.Editable = 'off';
            app.FairShareEditField.Tooltip = {'The users FairShare score at the last refresh.'};
            app.JobsTable = uitable(app.Tab);
            app.JobsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.JobsTable.RowName = {'''numbered'''};
            app.JobsTable.ColumnSortable = true;
            app.JobInfoText = uitextarea(app.Tab);
            app.TotalJobsLabel = uilabel(app.Tab);
            app.TotalJobsLabel.Text = 'Total Jobs Label';
            app.GroupsTable = uitable(app.Tab);
            app.GroupsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.GroupsTable.RowName = {};
            app.GroupsTable.CellSelectionCallback = createCallbackFcn(app, @GroupsTableCellSelection, true);
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.HTML = uihtml(app.HelpTab);
            app.HTML.HTMLSource = '<html> This GUI shows the accounting log of a SLURM HPC Cluster. <BR> <HR>  Start by selecting a date (or a range of dates). <BR> '', ...                             ''This will contact SLURM and show the jobs currently in the account log. <BR>  The refresh button updates this information (by contacting SLURM again) <BR>  <HR> '',...                            ''The panel on the left shows groups of jobs that belong together. <BR>  This relies on a convention for job-naming used by the @slurm class. <BR>'',...                            ''The panel on the right displays jobs in the selected group that ran <BR> on the cluster in the selected date range. Click on the headers to sort the jobs.<BR>'',...                            ''<HR> Color is used to indicate job state:  <br> <font color=00ff00>Completed Successfully</font> <br> <font color=ddaa00>Running now</font> <br>'',...                            ''<font color=0000ee>Failed previously but ok now.</font> <br> <font color=ee0000>Failed</font> <br> <font color=ee00ee>Failed more than 10 times</font> '',...                            ''<br> <br> The color of a group indicates the state of the worst job in the group. <BR> Any group with green or blue status therefore completed successfully (eventually). <HR> '',...                            ''Use keys to control jobs: <br> <b>o</b> opens a file showing Matlab command line output <br> <b>e</b> opens a file showing cluster and Matlab errors <br> '',...                            ''<b>r</b> retries selected jobs (or all failed jobs when a group is selected) <br> <b>c</b> cancels selected jobs (or all running jobs when a group is selected)<br> '',...                            ''<br> <b>g</b> retrieves the results of slurm.feval jobs (and assigns them to the ''''data'''' variable in the base workspace.br> '',...                            ''<br> <b>m</b> opens a file in the editor that shows the current usage of the cluster (smap) '',...                            ''<br><b>s</b> opens the sbatch .sh shell file in the editor.'',...                            ''<br><b>t</b> shows technical details about a job.'',...                                   ''<br><b>f</b> shows your fair share score.'',...                               ''<HR>  BK -March 2015,2018 </html> ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = slurmApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end